classdef AFM_Exosome_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        DisplayChannelButtonGroup       matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        LoadDataPanel                   matlab.ui.container.Panel
        OpenFileButton                  matlab.ui.control.Button
        FilePathEditFieldLabel          matlab.ui.control.Label
        FilePathEditField               matlab.ui.control.EditField
        ZeroChannelsPanel               matlab.ui.container.Panel
        ZeroHeightButton                matlab.ui.control.Button
        ZeroAdhesionButton              matlab.ui.control.Button
        PolynomialLevelingPanel         matlab.ui.container.Panel
        LevelButton                     matlab.ui.control.Button
        ThresholdHeightPanel            matlab.ui.container.Panel
        ThresholdButton                 matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        RemoveSmallorLargeObjectsPanel  matlab.ui.container.Panel
        SmallObjectsButton              matlab.ui.control.Button
        LargeObjectsButton              matlab.ui.control.Button
        ErosionanddilationPanel         matlab.ui.container.Panel
        SmallObjectsButton_2            matlab.ui.control.Button
        LargeObjectsButton_2            matlab.ui.control.Button
        DeclumpingPanel                 matlab.ui.container.Panel
        SmallObjectsButton_3            matlab.ui.control.Button
        LargeObjectsButton_3            matlab.ui.control.Button
        ChannelCentresPanel             matlab.ui.container.Panel
        SmallObjectsButton_4            matlab.ui.control.Button
        LargeObjectsButton_4            matlab.ui.control.Button
        OutputResultsPanel              matlab.ui.container.Panel
        SmallObjectsButton_5            matlab.ui.control.Button
        LargeObjectsButton_5            matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        pth = ''; % Path to image file
        fn = ''; % image file name
        I = struct; % Image file % Description
        h1; % image handle image 1 (raw height)
        h2; % image handle 2 (raw other channels)
        h3; % image handle 3 (processed height)
        h4; % image handle 4
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [app.fn, app.pth] = uigetfile([app.FilePathEditField.Value, '\*.*']);
            disp(app.FilePathEditField.Value)
            % try and load the Height channel
            try
                app.I.Height = readgwychannel([app.pth, app.fn], 0);
                app.I.HeightRaw = app.I.Height;
                app.ZeroHeightButton.Enable = true;
                app.ZeroAdhesionButton.Enable = true;
                app.LevelButton.Enable = true;
                app.ThresholdButton.Enable = true;
                app.FilterButton.Enable = true;
                app.FilePathEditField.Value = [app.pth, app.fn];
                
                app.h1 = imagesc(app.UIAxes, app.I.Height.data); drawnow;
                app.UIAxes.XAxis.Limits = app.h1.XData;
                app.UIAxes.YAxis.Limits = app.h1.YData;
                app.h1.ButtonDownFcn = {@im_ButtonDownFcn, app};
            catch ME
                disp(ME)
                disp("Height channel not found")
            end
            % try and load the stiffness channel
            try
                app.I.Stiffness = readgwychannel([app.pth, app.fn], 2);
            catch
                disp("Stiffness channel not found")                
            end
            % try and load the Adhesion channel
            try
                app.I.Adhesion = readgwychannel([app.pth, app.fn], 4);
            catch
                disp("Adhesion channel not found")
            end
            
            % try and load the Deformation channel
            try
                app.I.Deformation = readgwychannel([app.pth, app.fn], 5);
            catch
                disp("Deformation channel not found")
            end
            
            % try and load the Dissapation channel
            try
                app.I.Dissipation = readgwychannel([app.pth, app.fn], 6);
            catch
                disp("Dissipation channel not found")
            end 
        end

        % Button pushed function: ZeroHeightButton
        function ZeroHeightButtonPushed(app, event)
             app.I.Height.data = app.I.Height.data - (min(app.I.Height.data(:)));
% 
        end

        % Button pushed function: ZeroAdhesionButton
        function ZeroAdhesionButtonPushed(app, event)
            app.I.Adhesion.data = app.I.Adhesion.data - (min(app.I.Adhesion.data(:)));
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            thres = multithresh(app.I.Height.data./max(app.I.Height.data(:)), 1);
            app.I.Height.OtThrsData = app.I.Height.data;
            app.I.Height.OtThrsData(app.I.Height.OtThrsData < thres(1)*max(app.I.Height.OtThrsData(:))) = 0;
                app.h3 = imagesc(app.UIAxes_3, app.I.Height.OtThrsData); drawnow;
                app.UIAxes_3.XAxis.Limits = app.h3.XData;
                app.UIAxes_3.YAxis.Limits = app.h3.YData;
                
                app.SmallObjectsButton.Enable = true;
                app.LargeObjectsButton.Enable = true;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
        app.I.Height.data = medfilt2(app.I.Height.data);
            ThresholdButtonPushed(app, event)
        end

        % Button pushed function: SmallObjectsButton
        function SmallObjectsButtonPushed(app, event)
            
            %[x, y] = ginput(2)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Height Raw Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.DisplayChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayChannelButtonGroup.Title = 'Display Channel';
            app.Button = uiradiobutton(app.DisplayChannelButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.DisplayChannelButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.DisplayChannelButtonGroup);
            app.Button3.Text = 'Button3';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Final Results')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Height Processed')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTickLabel = '';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = '1) Load Data';
            app.OpenFileButton = uibutton(app.LoadDataPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.FilePathEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.LoadDataPanel, 'text');
            app.ZeroChannelsPanel = uipanel(app.UIFigure);
            app.ZeroChannelsPanel.Title = '2) Zero Channels';
            app.ZeroHeightButton = uibutton(app.ZeroChannelsPanel, 'push');
            app.ZeroHeightButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroHeightButtonPushed, true);
            app.ZeroHeightButton.Enable = 'off';
            app.ZeroHeightButton.Text = 'Zero Height';
            app.ZeroAdhesionButton = uibutton(app.ZeroChannelsPanel, 'push');
            app.ZeroAdhesionButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroAdhesionButtonPushed, true);
            app.ZeroAdhesionButton.Enable = 'off';
            app.ZeroAdhesionButton.Text = 'Zero Adhesion';
            app.PolynomialLevelingPanel = uipanel(app.UIFigure);
            app.PolynomialLevelingPanel.Title = '3) Polynomial Leveling';
            app.LevelButton = uibutton(app.PolynomialLevelingPanel, 'push');
            app.LevelButton.Enable = 'off';
            app.LevelButton.Text = 'Level';
            app.ThresholdHeightPanel = uipanel(app.UIFigure);
            app.ThresholdHeightPanel.Title = '4) Threshold Height';
            app.ThresholdButton = uibutton(app.ThresholdHeightPanel, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Threshold';
            app.FilterButton = uibutton(app.ThresholdHeightPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Text = 'Filter ';
            app.RemoveSmallorLargeObjectsPanel = uipanel(app.UIFigure);
            app.RemoveSmallorLargeObjectsPanel.Title = '6) Remove Small or Large Objects';
            app.SmallObjectsButton = uibutton(app.RemoveSmallorLargeObjectsPanel, 'push');
            app.SmallObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @SmallObjectsButtonPushed, true);
            app.SmallObjectsButton.Enable = 'off';
            app.SmallObjectsButton.Text = 'Small Objects';
            app.LargeObjectsButton = uibutton(app.RemoveSmallorLargeObjectsPanel, 'push');
            app.LargeObjectsButton.Enable = 'off';
            app.LargeObjectsButton.Text = 'Large Objects';
            app.ErosionanddilationPanel = uipanel(app.UIFigure);
            app.ErosionanddilationPanel.Title = '7) Erosion and dilation';
            app.SmallObjectsButton_2 = uibutton(app.ErosionanddilationPanel, 'push');
            app.SmallObjectsButton_2.Enable = 'off';
            app.SmallObjectsButton_2.Text = 'Small Objects';
            app.LargeObjectsButton_2 = uibutton(app.ErosionanddilationPanel, 'push');
            app.LargeObjectsButton_2.Enable = 'off';
            app.LargeObjectsButton_2.Text = 'Large Objects';
            app.DeclumpingPanel = uipanel(app.UIFigure);
            app.DeclumpingPanel.Title = '8) Declumping';
            app.SmallObjectsButton_3 = uibutton(app.DeclumpingPanel, 'push');
            app.SmallObjectsButton_3.Enable = 'off';
            app.SmallObjectsButton_3.Text = 'Small Objects';
            app.LargeObjectsButton_3 = uibutton(app.DeclumpingPanel, 'push');
            app.LargeObjectsButton_3.Enable = 'off';
            app.LargeObjectsButton_3.Text = 'Large Objects';
            app.ChannelCentresPanel = uipanel(app.UIFigure);
            app.ChannelCentresPanel.Title = '9) Channel Centres';
            app.SmallObjectsButton_4 = uibutton(app.ChannelCentresPanel, 'push');
            app.SmallObjectsButton_4.Text = 'Small Objects';
            app.LargeObjectsButton_4 = uibutton(app.ChannelCentresPanel, 'push');
            app.LargeObjectsButton_4.Text = 'Large Objects';
            app.OutputResultsPanel = uipanel(app.UIFigure);
            app.OutputResultsPanel.Title = '10) Output Results';
            app.SmallObjectsButton_5 = uibutton(app.OutputResultsPanel, 'push');
            app.SmallObjectsButton_5.Text = 'Small Objects';
            app.LargeObjectsButton_5 = uibutton(app.OutputResultsPanel, 'push');
            app.LargeObjectsButton_5.Text = 'Large Objects';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFM_Exosome_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end